classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        MultipleVanishingPointsModeButton  matlab.ui.control.Button
        vision_angleEditField       matlab.ui.control.NumericEditField
        vision_angleEditFieldLabel  matlab.ui.control.Label
        TranslationzEditField       matlab.ui.control.NumericEditField
        TranslationzEditFieldLabel  matlab.ui.control.Label
        TranslationyEditField       matlab.ui.control.NumericEditField
        TranslationyEditFieldLabel  matlab.ui.control.Label
        TranslationxEditField       matlab.ui.control.NumericEditField
        TranslationxEditFieldLabel  matlab.ui.control.Label
        RotationzEditField          matlab.ui.control.NumericEditField
        RotationzEditFieldLabel     matlab.ui.control.Label
        RotationyEditField          matlab.ui.control.NumericEditField
        RotationyEditFieldLabel     matlab.ui.control.Label
        RotationxEditField          matlab.ui.control.NumericEditField
        RotationxEditFieldLabel     matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        ObtainOutputimgButton       matlab.ui.control.Button
        SelectInputimgButton        matlab.ui.control.Button
        TourintothepictureLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        roi % Region of Inner Rectangle
        vanishingPoint % Description
        roiPos % Description
        vanishingPointPos % Description
        p1Pos % Description
        p2Pos % Description
        p3Pos % Description
        p4Pos % Description
        imgWidth % Description
        imgHeight % Description
        mode % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInputimgButton
        function select(app, event)
            app.mode=0;
            [file, path] = uigetfile('*.*');
            if isequal(file, 0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            %read the image
            app.img = imread(fullfile(path, file));

            %resize
            resize_scale=max(1.0,(size(app.img,1)*size(app.img,2))^0.5/600);
            app.img=imresize(app.img,1/resize_scale);
            

            %get size of the current image
            [Height, Width, ~]=size(app.img);
            app.imgWidth = Width;
            app.imgHeight = Height;
            
            %set axes properties
            title(app.UIAxes, "Input");
            app.UIAxes.XLim = [0 Width];
            app.UIAxes.YLim = [0 Height];
            
            %show image in axes
            imshow(app.img, 'parent', app.UIAxes);
            
            %define the original position of verteces in rectangle
            startPos = [floor(Width/4) floor(Height/4); 
                floor(Width/4*3) floor(Height/4);
                floor(Width/4*3) floor(Height/4*3); 
                floor(Width/4) floor(Height/4)*3];
            app.roiPos = [floor(Width/4) floor(Height/4) floor(Width/2) floor(Height/2)];
            app.roi = drawrectangle(app.UIAxes,'Position', app.roiPos, 'Color', 'r', 'FaceAlpha', 0);
            
            %draw the vanishing point in axes
            app.vanishingPointPos = [floor(Width/2) floor(Height/2)];
            app.vanishingPoint = drawpoint(app.UIAxes, ...
                'Position', app.vanishingPointPos, 'Color', 'r');
            
            
            %set the start postion of vertices, here we use clockwise order
            app.p1Pos = [startPos(1,1), startPos(1,2)];
            app.p2Pos = [startPos(2,1), startPos(2,2)];
            app.p3Pos = [startPos(3,1), startPos(3,2)];
            app.p4Pos = [startPos(4,1), startPos(4,2)];

            %Hier we calculate the intersection with outer border
            [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
            [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
            [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
            [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
            line1 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line2 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line3 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line4 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);

            %change and record current positions of vanishing point and rectangle
            addlistener(app.roi,'ROIMoved',@roimoved);
            addlistener(app.vanishingPoint,'ROIMoved',@vanishingPointMoved);
            function roimoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.roiPos = evt.CurrentPosition;
                        app.p1Pos = [floor(app.roiPos(1));floor(app.roiPos(2))];
                        app.p2Pos = [floor(app.roiPos(1)+app.roiPos(3));floor(app.roiPos(2))];
                        app.p3Pos = [floor(app.roiPos(1)+app.roiPos(3));floor(app.roiPos(2)+app.roiPos(4))];
                        app.p4Pos = [floor(app.roiPos(1));floor(app.roiPos(2)+app.roiPos(4))];
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
            function vanishingPointMoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.vanishingPointPos = floor(evt.CurrentPosition);
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
        end

        % Button pushed function: ObtainOutputimgButton
        function convert(app, event)
            X_pixel = [app.p1Pos(1) app.p2Pos(1) app.p3Pos(1) app.p4Pos(1);app.p1Pos(2) app.p2Pos(2) app.p3Pos(2) app.p4Pos(2)];
            R = create_R(app.RotationxEditField.Value, app.RotationyEditField.Value, app.RotationzEditField.Value);
            T = [app.TranslationxEditField.Value; app.TranslationyEditField.Value; app.TranslationzEditField.Value];
            v_3 = [app.vanishingPointPos(1); app.vanishingPointPos(2)];
            conversion(X_pixel, v_3, app.imgHeight, app.imgWidth, app.img, app.vision_angleEditField.Value, R, T);

            % this section is used to rebuild roi after click the convert
            % button,first we need to delete the old roi and use previous
            % positon to generate new roi, after rebuild the new roi, we
            % can drag roi as usual.
            delete(app.roi);
            delete(app.vanishingPoint);
            lines = findobj(app.UIAxes,'Type','images.roi.Line');
            delete(lines);
            %In this section we divide the process of rebuild into 2 case, 0 is for rectangle
            %case, while 1 is for complex case
            switch app.mode
                case 0
                    app.roi = drawrectangle(app.UIAxes,'Position', app.roiPos, 'Color', 'r', 'FaceAlpha', 0);
                    addlistener(app.roi,'ROIMoved',@roimoved_rect);
                case 1
                    app.roi = drawpolygon(app.UIAxes,'Position', app.roiPos, 'Color', 'r', 'FaceAlpha', 0);
                    addlistener(app.roi,'ROIMoved',@roimoved);
            end

            %Hier we calculate the intersection with outer border
            [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
            [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
            [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
            [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
            line1 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line2 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line3 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line4 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);

            % listener for rectangle case
            function roimoved_rect(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.roiPos = evt.CurrentPosition;
                        app.p1Pos = [floor(app.roiPos(1));floor(app.roiPos(2))];
                        app.p2Pos = [floor(app.roiPos(1)+app.roiPos(3));floor(app.roiPos(2))];
                        app.p3Pos = [floor(app.roiPos(1)+app.roiPos(3));floor(app.roiPos(2)+app.roiPos(4))];
                        app.p4Pos = [floor(app.roiPos(1));floor(app.roiPos(2)+app.roiPos(4))];
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
            % listener for complex case
            function roimoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.roiPos = evt.CurrentPosition;
                        app.p1Pos = [floor(app.roiPos(1,1));floor(app.roiPos(1,2))];
                        app.p2Pos = [floor(app.roiPos(2,1));floor(app.roiPos(2,2))];
                        app.p3Pos = [floor(app.roiPos(3,1));floor(app.roiPos(3,2))];
                        app.p4Pos = [floor(app.roiPos(4,1));floor(app.roiPos(4,2))];
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
            
            % hier we rebuild the vanishing point
            app.vanishingPoint = drawpoint(app.UIAxes, ...
                'Position', app.vanishingPointPos, 'Color', 'r');
            addlistener(app.vanishingPoint,'ROIMoved',@vanishingPointMoved);
            function vanishingPointMoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.vanishingPointPos = floor(evt.CurrentPosition);
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            cla(app.UIAxes);
            app.img = "";
            title(app.UIAxes, "");
            delete(app.roi);
            delete(app.vanishingPoint);
            lines = findobj(app.UIAxes,'Type','images.roi.Line');
            delete(lines);
        end

        % Button pushed function: MultipleVanishingPointsModeButton
        function ChangeROI(app, event)
            app.mode = 1;
            delete(app.roi);
            delete(app.vanishingPoint);
            lines = findobj(app.UIAxes,'Type','images.roi.Line');
            delete(lines);
            startPos = [floor(app.imgWidth/4) floor(app.imgHeight/4);
                            floor(app.imgWidth/4*3) floor(app.imgHeight/4);
                            floor(app.imgWidth/4*3) floor(app.imgHeight/4*3);
                            floor(app.imgWidth/4) floor(app.imgHeight/4*3)];
            %draw the roi in axes
            app.roiPos = startPos;
            app.roi = drawpolygon(app.UIAxes,'Position', app.roiPos, 'Color', 'r', 'FaceAlpha', 0);

            %draw the vanishing point in axes
            app.vanishingPointPos = [floor(app.imgWidth/2) floor(app.imgHeight/2)];
            app.vanishingPoint = drawpoint(app.UIAxes, ...
                'Position', app.vanishingPointPos, 'Color', 'r');
            
            %set the start postion of vertices, here we use clockwise order
            app.p1Pos = [startPos(1,1), startPos(1,2)];
            app.p2Pos = [startPos(2,1), startPos(2,2)];
            app.p3Pos = [startPos(3,1), startPos(3,2)];
            app.p4Pos = [startPos(4,1), startPos(4,2)];
            %Hier we calculate the intersection with outer border
            [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
            [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
            [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
            [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
            line1 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line2 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line3 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            line4 = drawline(app.UIAxes, 'Position', [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y], ...
                'InteractionsAllowed', 'none', 'LineWidth', 0.2);
            
            %change and record current positions of vanishing point and rectangle
            addlistener(app.roi,'ROIMoved',@roimoved);
            addlistener(app.vanishingPoint,'ROIMoved',@vanishingPointMoved);
            function roimoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.roiPos = evt.CurrentPosition;
                        app.p1Pos = [floor(app.roiPos(1,1));floor(app.roiPos(1,2))];
                        app.p2Pos = [floor(app.roiPos(2,1));floor(app.roiPos(2,2))];
                        app.p3Pos = [floor(app.roiPos(3,1));floor(app.roiPos(3,2))];
                        app.p4Pos = [floor(app.roiPos(4,1));floor(app.roiPos(4,2))];
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
            function vanishingPointMoved(src,evt)
                evname = evt.EventName;
                switch(evname)
                    case{'ROIMoved'}
                        app.vanishingPointPos = floor(evt.CurrentPosition);
                        [PB1x, PB1y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p1Pos(1), app.p1Pos(2), 0, 0);
                        [PB2x, PB2y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p2Pos(1), app.p2Pos(2), app.imgWidth, 0);
                        [PB3x, PB3y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p3Pos(1), app.p3Pos(2), app.imgWidth, app.imgHeight);
                        [PB4x, PB4y] = getPointAtBorder(app.vanishingPointPos(1), app.vanishingPointPos(2), app.p4Pos(1), app.p4Pos(2), 0, app.imgHeight);
                        line1.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB1x PB1y];
                        line2.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB2x PB2y];
                        line3.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB3x PB3y];
                        line4.Position = [app.vanishingPointPos(1) app.vanishingPointPos(2); PB4x PB4y];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TourintothepictureLabel = uilabel(app.UIFigure);
            app.TourintothepictureLabel.Text = 'Tour into the picture';
            app.SelectInputimgButton = uibutton(app.UIFigure, 'push');
            app.SelectInputimgButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.SelectInputimgButton.Text = 'Select Input img';
            app.ObtainOutputimgButton = uibutton(app.UIFigure, 'push');
            app.ObtainOutputimgButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.ObtainOutputimgButton.Text = 'Obtain Output img';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.RotationxEditFieldLabel = uilabel(app.UIFigure);
            app.RotationxEditFieldLabel.Text = 'Rotation x';
            app.RotationxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationyEditFieldLabel = uilabel(app.UIFigure);
            app.RotationyEditFieldLabel.Text = 'Rotation y';
            app.RotationyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationyEditField.Value = -30;
            app.RotationzEditFieldLabel = uilabel(app.UIFigure);
            app.RotationzEditFieldLabel.Text = 'Rotation z';
            app.RotationzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationxEditFieldLabel = uilabel(app.UIFigure);
            app.TranslationxEditFieldLabel.Text = 'Translation x';
            app.TranslationxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationxEditField.Value = 2;
            app.TranslationyEditFieldLabel = uilabel(app.UIFigure);
            app.TranslationyEditFieldLabel.Text = 'Translation y';
            app.TranslationyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationzEditFieldLabel = uilabel(app.UIFigure);
            app.TranslationzEditFieldLabel.Text = 'Translation z';
            app.TranslationzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TranslationzEditField.Value = 1.5;
            app.vision_angleEditFieldLabel = uilabel(app.UIFigure);
            app.vision_angleEditFieldLabel.Text = 'vision_angle';
            app.vision_angleEditField = uieditfield(app.UIFigure, 'numeric');
            app.vision_angleEditField.Value = 60;
            app.MultipleVanishingPointsModeButton = uibutton(app.UIFigure, 'push');
            app.MultipleVanishingPointsModeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeROI, true);
            app.MultipleVanishingPointsModeButton.Text = 'Multiple Vanishing Points Mode ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'picture', 'gui.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end